classdef ECGuide1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptionsMenu                     matlab.ui.container.Menu
        ResetApplicationMenu            matlab.ui.container.Menu
        ResetROIMenu                    matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ParameterTab                    matlab.ui.container.Tab
        inputFolder                     matlab.ui.control.Button
        BrowsethepathofthestoredECGRecordsfolderLabel  matlab.ui.control.Label
        inpuText                        matlab.ui.control.Label
        BrowsethepathoftheoutputECGresultsfolderLabel  matlab.ui.control.Label
        outputFolder                    matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        Button3                         matlab.ui.control.RadioButton
        startparsingButton              matlab.ui.control.Button
        mainFolder                      matlab.ui.control.Label
        outputText                      matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        SpecifythedesiredtypeoftemplateDropDownLabel  matlab.ui.control.Label
        Dropdown                        matlab.ui.control.DropDown
        HowmanyECGsegmentsarecontainedinasingleECGrecordLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        EditFieldLabel                  matlab.ui.control.Label
        WhatthresholdisrequiredforthecurrentbatchofECGrecordsEditField  matlab.ui.control.EditField
        EditFieldLabel_2                matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        GraphTab                        matlab.ui.container.Tab
        startcroppingButton             matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        StartROIButton                  matlab.ui.control.Button
        ThresholdTab                    matlab.ui.container.Tab
        ThresholdValueEditFieldLabel    matlab.ui.control.Label
        thresholdValue                  matlab.ui.control.NumericEditField
        Slider                          matlab.ui.control.Slider
        StartContourCalculationsButton  matlab.ui.control.Button
        PreviousButton_2                matlab.ui.control.Button
        NextButton_3                    matlab.ui.control.Button
        thresholdAxes                   matlab.ui.control.UIAxes
        OCRTab                          matlab.ui.container.Tab
        BrowsethepathofthestoredOCRtemplatesfileLabel  matlab.ui.control.Label
        ocrFolder                       matlab.ui.control.Button
        DoyouwantcharacterremovalLabel  matlab.ui.control.Label
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        YesButton_2                     matlab.ui.control.RadioButton
        NoButton_2                      matlab.ui.control.RadioButton
        Button3_3                       matlab.ui.control.RadioButton
        TracingLinesButtonarent         matlab.ui.control.Button
        PreviousButton_3                matlab.ui.control.Button
        ocrText                         matlab.ui.control.Label
        NextButton_4                    matlab.ui.control.Button
        baseFile                        matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        baseText                        matlab.ui.control.Label
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        threeButton                     matlab.ui.control.RadioButton
        fourButton                      matlab.ui.control.RadioButton
        randm                           matlab.ui.control.RadioButton
        fiveButton                      matlab.ui.control.RadioButton
        sixButton                       matlab.ui.control.RadioButton
        sevenButton                     matlab.ui.control.RadioButton
        eightButton                     matlab.ui.control.RadioButton
        nineButton                      matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        calRecord % Folder containing main input folder path
        endgame % number of ECG segments
        seg1 %Intermediate Variables
        seg2
        seg3
        seg4
        xi
        yi
        P
        contour 
        xi_1
        yi_1
        P_1
        contour_1 
        xi_2
        yi_2
        P_2
        contour_2 
        xi_3
        yi_3
        P_3
        contour_3 
        xi_4
        yi_4
        P_4
        contour_4 
        currentFolder
        segment1
        segment2
        segment3
        segment4
        imageDemographic
        imageContour
        thresholdContour
        rootFolder
        thresh
        final
        roiwindow
        roi
        labels
        labels_number
        rgb_images
        u
        basefile
        roi_shift
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    
            image = imread("title.jpg");
            imshow(image,'Parent',app.thresholdAxes)
            app.contour_1 = load('contour_1.mat');
            app.xi_1 = load('xi_1.mat');
            app.yi_1 = load('yi_1.mat');
            app.P_1 = load('P_1.mat');
            app.contour_2 = load('contour_2.mat');
            app.xi_2 = load('xi_2.mat');
            app.yi_2 = load('yi_2.mat');
            app.P_2 = load('P_2.mat');
            app.contour_3 = load('contour_3.mat');
            app.xi_3 = load('xi_3.mat');
            app.yi_3 = load('yi_3.mat');
            app.P_3 = load('P_3.mat');
            app.contour_4 = load('contour_4.mat');
            app.xi_4 = load('xi_4.mat');
            app.yi_4 = load('yi_4.mat');
            app.P_4 = load('P_4.mat');
            app.NextButton.Enable = 'off';
            app.NextButton_2.Enable = 'off';
            app.NextButton_3.Enable = 'off';
            app.BrowsethepathofthestoredOCRtemplatesfileLabel.Enable = 'off'; 
            app.BrowsethepathofthestoredOCRtemplatesfileLabel.Visible = 'off'; 
            app.ocrFolder.Enable = 'off';
            app.ocrFolder.Visible = 'off';
            app.ocrText.Visible ='off';
            app.ocrText.Enable = 'off';
            app.text1.Enable = 'off';
            app.text1.Visible = 'off';
            app.baseFile.Visible = 'off';
            app.baseFile.Enable = 'off';
            app.baseText.Visible = 'off';
            app.baseText.Enable = 'off'; 
        end

        % Button pushed function: inputFolder
        function inputFolderButtonPushed(app, event)
            app.currentFolder = cd; 
            disp(app.currentFolder)
            app.mainFolder.Text = uigetdir(path,'Open Directory');
            figure(app.UIFigure)
        end

        % Button pushed function: baseFile
        function baseFileButtonPushed(app, event)
        if isempty(app.YesButton.Value) || isempty(app.NoButton.Value)
            %pop up window
            f = uifigure; 
            uialert(f,'Selection Required','Please select yes or no')
        end
            clc
            app.baseText.Text = uigetfile(path,'Open Directory');
            figure(app.UIFigure)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.YesButton.Value == 1
                app.text1.Enable = 'off' ;
                app.baseText.Enable = 'off'; 
                app.baseFile.Visible = 'off';
                app.startcroppingButton.Visible = 'off';
                app.startparsingButton.Enable = 'on';
                app.calRecord = 'YES';
               
            elseif app.NoButton.Value == 1 
                app.baseFile.Visible = 'off';
                app.text1.Enable = 'off' ;
                app.startparsingButton.Enable = 'off'; 
                app.startcroppingButton.Visible = 'on';
                app.calRecord = 'NO';
                
                app.baseText.Enable = 'off';
            end
        end

        % Button pushed function: outputFolder
        function outputFolderButtonPushed(app, event)
            app.outputText.Text = uigetdir(path,'Open Directory');
            app.NextButton.Enable = 'off';
            if app.NoButton.Value == 1
                app.NextButton.Enable = 'on';
            end
            figure(app.UIFigure)
        end

        % Button pushed function: startparsingButton
        function startparsingButtonPushed(app, event)

            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Calibrating Parameters');
            d.Value = .10;
            [app.contour,app.u] = calibratingapp(app);
            disp(app.contour)
            d.Value = .30;
            d.Message = 'Reading Files';
            [imageArray] = readfiles(app.mainFolder.Text);
            imageArraygrey = checkcolor(imageArray);
            d.Value = .60;
            d.Message = 'Marking Regions';
            pause(.5)
            [app.imageContour,app.imageDemographic] = markingregions(imageArraygrey,app.contour,app);%marking out ECG signal regions from the demographic regions
            d.Value = .80;
            d.Message = 'Segmenting Images';
            pause(.5)
%            [app.seg1,app.seg2,app.seg3,app.seg4] = segmentingimages(app.imageContour,app,app.xi,app.yi); %segmenting the images based on the parameters initialized
            d.Value = 1;
            d.Message = 'Finished'; 
            pause(1)
            app.NextButton.Enable = 'on';
            app.startparsingButton.Enable = 'off';
            close(d)
            close(f)
        end

        % Callback function
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if app.threeButton.Value == 1
                app.endgame = 3;
            elseif app.fourButton.Value == 1 
                app.endgame = 4;
            elseif app.fiveButton.Value == 1 
                app.endgame = 5;
            elseif app.sixButton.Value == 1 
                app.endgame = 6;
            elseif app.sevenButton.Value == 1 
                app.endgame = 7;
            elseif app.eightButton.Value == 1 
                app.endgame = 8;
            elseif app.nineButton.Value == 1 
                app.endgame = 9;
            end
        end

        % Button pushed function: StartContourCalculationsButton
        function StartContourCalculationsButtonPushed(app, event)
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Grid Thresholding');
            pause(1);
            if strcmp(app.calRecord,'NO') 
                [app] = saving_roi(app);
            end
            d.Value = 0.5;
            [app.thresholdContour] = gridthreshold(app);%removing the grid,and median filtering
%            app = adjust_roi(app);
            d.Value = 1; 
            d.Message = 'Finished';
            pause(1);
            close(d);
            close(f);
            app.StartContourCalculationsButton.Enable = 'off';
            app.NextButton_3.Enable = 'on';
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.thresholdValue.Value = value;
            imshow(app.imageContour{1}<value,'Parent',app.thresholdAxes)
            set(app.thresholdAxes,'visible','off');
            title(app.thresholdAxes, []);
            xlabel(app.thresholdAxes, []);
            ylabel(app.thresholdAxes, []);
            app.thresholdAxes.XAxis.TickLabels = {};
            app.thresholdAxes.YAxis.TickLabels = {};
        end

        % Button pushed function: startcroppingButton
        function startcroppingButtonPushed(app, event)

            [app.contour,app.u] = calibratingapp(app);%calibration step(user input involved)
            app.startcroppingButton.Enable = 'off';
        end

        % Callback function
        function StartParsingButtonPushed(app, event)
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Reading Files');
            d.Value = .10;
            [imageArray] = readfiles(app.mainFolder.Text);
            imageArraygrey = checkcolor(imageArray);
            d.Value = .40;
            d.Message = 'Marking Regions';
            pause(.5)
            [app.imageContour,app.imageDemographic] = markingregions(imageArraygrey,app.contour,app);%marking out ECG signal regions from the demographic regions
            d.Value = .80;
            d.Message = 'Segmenting Images';
            pause(.5)
%            [app.seg1,app.seg2,app.seg3,app.seg4] = segmentingimages(app.imageContour,app,app.xi,app.yi); %segmenting the images based on the parameters initialized
            d.Value = 1; 
            d.Message = 'Finished'; 
            pause(1)            
            app.StartParsingButton.Enable = 'off';
            app.NextButton_2.Enable = 'on';
            close(d);
            close(f);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if strcmp(app.calRecord,'NO')
                 app.TabGroup.SelectedTab = app.GraphTab;
            elseif strcmp(app.calRecord,'YES')
                 app.TabGroup.SelectedTab = app.ThresholdTab;
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParameterTab;
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.ThresholdTab;
        end

        % Button pushed function: ocrFolder
        function ocrFolderButtonPushed(app, event)
            app.ocrText.Text = uigetfile(path,'Open Directory');
            figure(app.UIFigure)
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.OCRTab;
        end

        % Button pushed function: PreviousButton_3
        function PreviousButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.ThresholdTab;
        end

        % Menu selected function: ResetROIMenu
        function ResetROIMenuSelected(app, event)
            
        end

        % Button pushed function: TracingLinesButtonarent
        function TracingLinesButtonarentPushed(app, event)
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Connected Component Analysis');
            d.Value = .10;
            pause(.5);
            app = cca_algorithm(app);
            app = adjust_roi(app);
% %             [app] = test_roi(app)
            d.Value = .30;
            d.Message = 'Character Removal';
            pause(1)
            app = characteremoval(app);
            d.Value = .50;
            d.Message = 'Tracing Voltage';
            pause(1)
            app = tracingvoltage(app);
            d.Value = .50;
            d.Message = 'Saving Results';
            app = finalresults(app);
            d.Value = 1;
            d.Message = 'Finished';
            pause(1);
            close(d);
            close(f);
%             app = linearfiltering(app);
%             for i = 1:length(app.thresholdContour)
%                 CC = bwconncomp(app.thresholdContour{i},4);
%                 numPixels = cellfun(@numel,CC.PixelIdxList);
%                 L1 = labelmatrix(CC);
%                 a = find(numPixels>50000);
%                 app.endgame = length(a);
%                 for j = a
%                     coloredLabels = label2rgb ((L1==j), 'hsv', 'k', 'shuffle');
%                     app.thresh{i}{j} = coloredLabels;
%                 end
%             end
            
%             if app.endgame == 3
%                 load('roi3.mat')
%             elseif app.endgame == 4 || app.endgame == 5 || app.endgame == 6|| app.endgame == 7 || app.endgame == 8 || app.endgame == 9
%                 load('roi4.mat')
%             end
%             
%             if app.YesButton_2 == 1 
%                 d.Value = .30;
%                 d.Message = 'Character Removal';
%                 pause(1)
%                 [app] = characteremoval(app,roi);
%             end
%             
%             d.Value = .50;
%             d.Message = 'Linear Filtering';
%             [app.thresh] = linearfiltering(app);
%             
%             

%             d.Message = 'Tracing Voltage';
%             [app.final] = tracingvoltage(app);
%             d.Value = .80;
%             d.Message = 'Saving Results';
% %            %[wow] = finalresults(segment_1,segment_2,segment_3,segment_4,app);
% %            imshow(Segment1{1}{1},'Parent',app.UIaxes)
%             d.Value = .1;
%             d.Message = 'Finished';
            
            %imshow(app.seg1{1}{1},'Parent',app.UIAxes3)
            %hold(app.UIAxes3,"on")
            %remove title and axes labels
%            title(app.UIAxes3,"");
%            xlabel(app.UIAxes3,"");
%            ylabel(app.UIAxes3,"");
            %plot two diagonal lines on image

            %f = figure;

            %a = uipanel('Title','Panel', 'Position',[.1 .1 .8 .8]);
            %b = uipanel(f,'Position',[10 10 400 400]);
            %ax1 = axes(a);
            %ax2 = uiaxes(b,'Position',[10 10 390 390])
%            plot(app.UIAxes3,time,signal,'b','LineWidth',2);
%            set(app.UIAxes3,'Color','none')
%            imshow(app.seg1{1}{1},'Parent',app.UIAxes4)
            
            %plot(app.UIAxes3,0:imgWidth,imgHeight:-1:0,'r','LineWidth',5);
            %hold(app.UIAxes3,"off")
            %images.roi.Line(app.UIAxes,'Color','green','LineWidth',5)
            
%            fig = uifigure;
%            ax = uiaxes(fig)
            %fig = uifigure;
            %p = uipanel(fig,'Position',[10 10 400 400]);
            %ax = uiaxes(p,'Position',[10 10 390 390]);
            %ax = uiaxes(p,'Position',[10 10 390 390]);
%             signal = app.final{1}{1};
%             dc = nanmean(signal);
%             signal = (signal - dc)*4.23e-6;
%             time = linspace(0,length(signal),length(signal))* 1.693e-3;
% 
% 
%             imshow(app.rgb_images{1}{1},'Parent',app.UIAxes)
%             title(app.UIAxes,"");
%             xlabel(app.UIAxes,"");
%             ylabel(app.UIAxes,"");
%             
%             
%             plot(app.UIAxes5,time,signal)
%             set(app.UIAxes5,'Color','none')
%             title(app.UIAxes5,"");
%             xlabel(app.UIAxes5,"");
%             ylabel(app.UIAxes5,"");
% % %             
% % %            plot(app.UIAxes2,time,signal)
% % %            set(app.UIAxes2,'Color','none')
% %             
% %             
%            app.TracingLinesButtonarent.Enable = 'off';
%             close(d);
%             close(f);
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            if app.calRecord == 'YES'
                app.TabGroup.SelectedTab = app.ParameterTab; 
            elseif app.calRecord == 'NO' 
                app.TabGroup.SelectedTab = app.GraphTab;
            end      
        end

        % Menu selected function: ResetApplicationMenu
        function ResetApplicationMenuSelected(app, event)
            image = imread("title.jpg");
            imshow(image,'Parent',app.thresholdAxes)
            app.TabGroup.SelectedTab = app.ParameterTab;
            app.mainFolder.Text = '';
            app.Button3.Value = 1;
            app.baseText.Text = '';
            app.randm.Value = 1;
            app.outputText.Text = ''; 
            app.NextButton.Enable = 'off';
            app.PleaseProceedtothenexttabLabel.Visible = 'off';
            app.NextButton_2.Enable = 'off';
            app.NextButton_3.Enable = 'off';
            app.ocrText.Text = ''; 
            app.Button3_3.Value = 1; 
            app.TracingLinesButtonarent.Enable = 'on';
            app.StartContourCalculationsButton.Enable = 'on'; 
            app.startcroppingButton.Enable = 'on'; 
            app.startparsingButton.Enable = 'on'; 
            app.text1.Enable = 'on';
            app.baseFile.Enable = 'on';
            app.baseText.Enable = 'on';
            app.StartParsingButton.Enable = 'on'; 
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.ComparisonTab;
        end

        % Button pushed function: StartROIButton
        function StartROIButtonPushed(app, event)
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Reading Files');
            d.Value = .10;
            [imageArray] = readfiles(app.mainFolder.Text);
            imageArraygrey = checkcolor(imageArray);
            d.Value = .40;
            d.Message = 'Marking Regions';
            pause(.5)
            [imageContour,app.imageDemographic] = markingregions(imageArraygrey,app.contour,app);%marking out ECG signal regions from the demographic regions
            myimage = imageContour{1}<65;
            app.imageContour = imageContour;
            app.roiwindow = CROIEditor(myimage);
            
            d.Value = .80;
            d.Message = 'Segmenting Images';
            pause(.5)
%            [app.seg1,app.seg2,app.seg3,app.seg4] = segmentingimages(app.imageContour,app,app.xi,app.yi); %segmenting the images based on the parameters initialized
            d.Value = 1; 
            d.Message = 'Finished'; 
            pause(1)            
            app.StartROIButton.Enable = 'off';
            app.NextButton_2.Enable = 'on';
            close(d);
            close(f);
            
        end

        % Selection changed function: ButtonGroup_4
        function ButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_4.SelectedObject;
            if app.threeButton.Value == 1
                app.endgame = 3;
            elseif app.fourButton.Value == 1 
                app.endgame = 4;
            elseif app.fiveButton.Value == 1 
                app.endgame = 5;
            elseif app.sixButton.Value == 1 
                app.endgame = 6;
            elseif app.sevenButton.Value == 1 
                app.endgame = 7;
            elseif app.eightButton.Value == 1 
                app.endgame = 8;
            elseif app.nineButton.Value == 1 
                app.endgame = 9;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ResetApplicationMenu = uimenu(app.OptionsMenu);
            app.ResetApplicationMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetApplicationMenuSelected, true);
            app.ResetApplicationMenu.Text = 'Reset Application';
            app.ResetROIMenu = uimenu(app.OptionsMenu);
            app.ResetROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetROIMenuSelected, true);
            app.ResetROIMenu.Text = 'Reset ROI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter Tab ';
            app.inputFolder = uibutton(app.ParameterTab, 'push');
            app.inputFolder.ButtonPushedFcn = createCallbackFcn(app, @inputFolderButtonPushed, true);
            app.inputFolder.Text = 'Browse Folder';
            app.BrowsethepathofthestoredECGRecordsfolderLabel = uilabel(app.ParameterTab);
            app.BrowsethepathofthestoredECGRecordsfolderLabel.Text = 'Browse the path of the stored ECG Records folder: ';
            app.inpuText = uilabel(app.ParameterTab);
            app.inpuText.Text = 'Continue with previous calibration characteristics? ';
            app.BrowsethepathoftheoutputECGresultsfolderLabel = uilabel(app.ParameterTab);
            app.BrowsethepathoftheoutputECGresultsfolderLabel.Text = 'Browse the path of the output ECG results folder:';
            app.outputFolder = uibutton(app.ParameterTab, 'push');
            app.outputFolder.ButtonPushedFcn = createCallbackFcn(app, @outputFolderButtonPushed, true);
            app.outputFolder.Text = 'Browse Folder';
            app.ButtonGroup = uibuttongroup(app.ParameterTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.YesButton = uiradiobutton(app.ButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.ButtonGroup);
            app.NoButton.Text = 'No';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.Button3.Value = true;
            app.startparsingButton = uibutton(app.ParameterTab, 'push');
            app.startparsingButton.ButtonPushedFcn = createCallbackFcn(app, @startparsingButtonPushed, true);
            app.startparsingButton.Text = 'Start Parsing';
            app.mainFolder = uilabel(app.ParameterTab);
            app.mainFolder.Text = '';
            app.outputText = uilabel(app.ParameterTab);
            app.outputText.Text = '';
            app.NextButton = uibutton(app.ParameterTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SpecifythedesiredtypeoftemplateDropDownLabel = uilabel(app.ParameterTab);
            app.SpecifythedesiredtypeoftemplateDropDownLabel.Text = 'Specify the desired type of template';
            app.Dropdown = uidropdown(app.ParameterTab);
            app.Dropdown.Items = {'GE CASE V6.51', 'MAC500K 003A', 'New Template 1', 'New Template 2'};
            app.Dropdown.Value = 'GE CASE V6.51';
            app.HowmanyECGsegmentsarecontainedinasingleECGrecordLabel = uilabel(app.ParameterTab);
            app.HowmanyECGsegmentsarecontainedinasingleECGrecordLabel.Text = 'How many ECG segments are contained in a single ECG record? ';
            app.DropDown = uidropdown(app.ParameterTab);
            app.DropDown.Items = {'3', '4', '5', '6'};
            app.DropDown.Value = '3';
            app.EditFieldLabel = uilabel(app.ParameterTab);
            app.EditFieldLabel.Text = 'Specify the pixel threshold required for the current batch of ECG records:';
            app.WhatthresholdisrequiredforthecurrentbatchofECGrecordsEditField = uieditfield(app.ParameterTab, 'text');
            app.EditFieldLabel_2 = uilabel(app.ParameterTab);
            app.EditFieldLabel_2.Text = 'Specify the distance threshold required for the current batch of ECG records: ';
            app.EditField = uieditfield(app.ParameterTab, 'text');
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph Tab';
            app.startcroppingButton = uibutton(app.GraphTab, 'push');
            app.startcroppingButton.ButtonPushedFcn = createCallbackFcn(app, @startcroppingButtonPushed, true);
            app.startcroppingButton.Text = 'Start Cropping';
            app.PreviousButton = uibutton(app.GraphTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton_2 = uibutton(app.GraphTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.StartROIButton = uibutton(app.GraphTab, 'push');
            app.StartROIButton.ButtonPushedFcn = createCallbackFcn(app, @StartROIButtonPushed, true);
            app.StartROIButton.Text = 'Start ROI';
            app.ThresholdTab = uitab(app.TabGroup);
            app.ThresholdTab.Title = 'Threshold Tab';
            app.ThresholdValueEditFieldLabel = uilabel(app.ThresholdTab);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.thresholdValue = uieditfield(app.ThresholdTab, 'numeric');
            app.Slider = uislider(app.ThresholdTab);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.StartContourCalculationsButton = uibutton(app.ThresholdTab, 'push');
            app.StartContourCalculationsButton.ButtonPushedFcn = createCallbackFcn(app, @StartContourCalculationsButtonPushed, true);
            app.StartContourCalculationsButton.Text = 'Start Contour Calculations';
            app.PreviousButton_2 = uibutton(app.ThresholdTab, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = 'Previous';
            app.NextButton_3 = uibutton(app.ThresholdTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.thresholdAxes = uiaxes(app.ThresholdTab);
            xlabel(app.thresholdAxes, 'X')
            ylabel(app.thresholdAxes, 'Y')
            app.OCRTab = uitab(app.TabGroup);
            app.OCRTab.Title = 'OCR Tab';
            app.BrowsethepathofthestoredOCRtemplatesfileLabel = uilabel(app.OCRTab);
            app.BrowsethepathofthestoredOCRtemplatesfileLabel.Text = 'Browse the path of the stored OCR templates file: ';
            app.ocrFolder = uibutton(app.OCRTab, 'push');
            app.ocrFolder.ButtonPushedFcn = createCallbackFcn(app, @ocrFolderButtonPushed, true);
            app.ocrFolder.Text = 'Browse File';
            app.DoyouwantcharacterremovalLabel = uilabel(app.OCRTab);
            app.DoyouwantcharacterremovalLabel.Text = 'Do you want character removal? ';
            app.ButtonGroup_3 = uibuttongroup(app.OCRTab);
            app.YesButton_2 = uiradiobutton(app.ButtonGroup_3);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uiradiobutton(app.ButtonGroup_3);
            app.NoButton_2.Text = 'No';
            app.Button3_3 = uiradiobutton(app.ButtonGroup_3);
            app.Button3_3.Visible = 'off';
            app.Button3_3.Text = 'Button3';
            app.Button3_3.Value = true;
            app.TracingLinesButtonarent = uibutton(app.OCRTab, 'push');
            app.TracingLinesButtonarent.ButtonPushedFcn = createCallbackFcn(app, @TracingLinesButtonarentPushed, true);
            app.TracingLinesButtonarent.Text = 'Tracing Lines';
            app.PreviousButton_3 = uibutton(app.OCRTab, 'push');
            app.PreviousButton_3.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_3Pushed, true);
            app.PreviousButton_3.Text = 'Previous';
            app.ocrText = uilabel(app.OCRTab);
            app.ocrText.Text = '';
            app.NextButton_4 = uibutton(app.OCRTab, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Text = 'Next';
            app.baseFile = uibutton(app.UIFigure, 'push');
            app.baseFile.ButtonPushedFcn = createCallbackFcn(app, @baseFileButtonPushed, true);
            app.baseFile.Text = 'Browse File';
            app.text1 = uilabel(app.UIFigure);
            app.text1.Text = 'If No, set a path to browse a base file of your choice:';
            app.baseText = uilabel(app.UIFigure);
            app.baseText.Text = '';
            app.ButtonGroup_4 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_4SelectionChanged, true);
            app.threeButton = uiradiobutton(app.ButtonGroup_4);
            app.threeButton.Text = '3';
            app.fourButton = uiradiobutton(app.ButtonGroup_4);
            app.fourButton.Text = '4';
            app.randm = uiradiobutton(app.ButtonGroup_4);
            app.randm.Visible = 'off';
            app.randm.Text = 'Button3';
            app.randm.Value = true;
            app.fiveButton = uiradiobutton(app.ButtonGroup_4);
            app.fiveButton.Text = '5';
            app.sixButton = uiradiobutton(app.ButtonGroup_4);
            app.sixButton.Text = '6';
            app.sevenButton = uiradiobutton(app.ButtonGroup_4);
            app.sevenButton.Text = '7';
            app.eightButton = uiradiobutton(app.ButtonGroup_4);
            app.eightButton.Text = '8';
            app.nineButton = uiradiobutton(app.ButtonGroup_4);
            app.nineButton.Text = '9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECGuide1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end